classdef Cleaning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EditButton                   matlab.ui.control.Button
        RadarSelectionDropDownLabel  matlab.ui.control.Label
        RadarSelectionDropDown       matlab.ui.control.DropDown
        AxesDens                     matlab.ui.control.UIAxes
        AxesReflectivity             matlab.ui.control.UIAxes
        previous                     matlab.ui.control.Button
        next                         matlab.ui.control.Button
        SavetofileButton             matlab.ui.control.Button
    end


    properties (Access = private)
        d;
        dt;
        i_d=1;
        fig=1;
        button;
        start_date=datetime(2016,09,19,12,00,00);
        end_date=datetime(2016,10,9,12,00,00);
    end

    methods (Access = private)
        
        function rect(app,val)
            set(app.button.nan,'Enable','off'); set(app.button.zero,'Enable','off'); set(app.button.fill,'Enable','off'); 
            set(app.button.fill0,'Enable','off'); set(app.button.fillnan,'Enable','off'); set(app.button.copyline,'Enable','off');set(app.button.refresh,'Enable','off');
            figure(app.fig); subplot(2,1,1);
            rect = getrect(gca);
            x = datenum(app.d(app.i_d).time);
            y = app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels));
            idx = x>=rect(1) & x<=rect(1)+rect(3);
            idy = y>=rect(2) & y<=rect(2)+rect(4);
            [X,Y]=meshgrid(x(idx),y(idy));
            figure(app.fig); hold on; scatter(X(:),Y(:),'r.')
            switch val
                case 'nan'
                    app.dt(idx,idy)=NaN;
                case'zero'
                    app.dt(idx,idy)=0;
                case 'fill'
                    V = app.dt;
                    id_nan =isnan(V);
                    V(idx,idy)=nan;
                    V=fillmissing(V,'pchip');
                    V(V<0)=0;
                    V(id_nan)=NaN;
                    app.dt=V;
                case 'fill0'
                    V = app.dt;
                    id_nan =isnan(V);
                    a=V(idx,idy);
                    a(a==0)=nan; 
                    V(idx,idy)=a;
                    V=fillmissing(V,'pchip');
                    V(V<0)=0;
                    V(id_nan)=NaN;
                    app.dt=V;
                case 'fillnan'
                    V = app.dt;
                    V(isnan(V)) = -1000;
                    a=V(idx,idy);
                    a(a<-999)=nan; 
                    V(idx,idy)=a;
                    V=fillmissing(V,'pchip');
                    V(V<-999)=NaN;
                    V(V<0)=0;
                    app.dt=V;
                case 'copyline'
                    id_copy = find(idy, 1 );
                    for id_y=1:id_copy-1
                        app.dt(idx,id_y) = app.dt(idx,id_copy);
                    end
                case 'multiply'
                    answer = str2double(inputdlg('Value to multiply by'));
                    app.dt(idx,idy) = answer.*app.dt(idx,idy);
            end
            refreshFig(app)
        end
        
        function refreshFig(app)
            figure(app.fig); a_x = axis; clf;
            ax1=subplot(2,1,1); hold on;
            h=surf(datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels)), log(app.dt+1)','AlphaData',~isnan(app.dt'),'EdgeColor','none');
            set(h,'ZData',get(h,'ZData')-10)
            plot([datenum(app.start_date) datenum(app.end_date)],[app.d(app.i_d).height app.d(app.i_d).height],'k','linewidth',2); view(2)
            plot(repmat(datenum(unique(app.d(app.i_d).sunrise)),2,1),[0 5000],'-k'); 
            plot(repmat(datenum(unique(app.d(app.i_d).sunset)),2,1),[0 5000],'-k');
            datetick('x');set(gca,'XTick',datenum(app.d(app.i_d).time(1):6/24:app.d(app.i_d).time(end)))
            set(gca,'XTickLabel',datestr(app.d(app.i_d).time(1):6/24:app.d(app.i_d).time(end),'dd-mm HH:MM'))
            
            
            ax2=subplot(2,1,2); hold on;
            h=surf(datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels)), app.d(app.i_d).DBZH','AlphaData',~isnan(app.d(app.i_d).DBZH'),'EdgeColor','none');
            set(h,'ZData',get(h,'ZData')-100);
            plot(datenum(app.d(app.i_d).time), app.d(app.i_d).tcrw*5000/max( app.d(app.i_d).tcrw),'r-','linewidth',2) ; view(2)
            datetick('x');set(gca,'XTick',datenum(app.d(app.i_d).time(1):1/24:app.d(app.i_d).time(end))); legend(num2str(max(app.d(app.i_d).tcrw)));
            set(gca,'XTickLabel',datestr(app.d(app.i_d).time(1):1/24:app.d(app.i_d).time(end),'dd-mm HH:MM'))
            
            linkaxes([ax1,ax2],'xy')
            if ~all(a_x==[0 1 0 1]); axis(a_x);else, axis tight; end
                        
            app.button.nan=uicontrol('Parent',app.fig,'Style','pushbutton','String','Fill with NaN','Units','normalized','Position',[0.01 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'nan'));
            app.button.zero=uicontrol('Parent',app.fig,'Style','pushbutton','String','Fill with 0','Units','normalized','Position',[0.08 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'zero'));
            app.button.fill=uicontrol('Parent',app.fig,'Style','pushbutton','String','Interpolation','Units','normalized','Position',[0.15 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'fill'));
            app.button.fill0=uicontrol('Parent',app.fig,'Style','pushbutton','String','Interpolation only 0','Units','normalized','Position',[0.22 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'fill0'));
            app.button.fillnan=uicontrol('Parent',app.fig,'Style','pushbutton','String','Interpolation only NaN','Units','normalized','Position',[0.29 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'fillnan'));
            app.button.copyline=uicontrol('Parent',app.fig,'Style','pushbutton','String','Copy line below','Units','normalized','Position',[0.36 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'copyline'));
            app.button.copyline=uicontrol('Parent',app.fig,'Style','pushbutton','String','Multiply value','Units','normalized','Position',[0.43 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'multiply'));
            app.button.refresh=uicontrol('Parent',app.fig,'Style','pushbutton','String','Save changes','Units','normalized','Position',[0.92 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) saveFig(app));
        end
        
        function saveFig(app)
            app.d(app.i_d).dens=app.dt;
            refreshAxes(app)
        end
        
        function refreshAxes(app)
            app.RadarSelectionDropDown.Value = app.d(app.i_d).name;
            
            cla(app.AxesDens); hold(app.AxesDens,'on')
            imagesc(app.AxesDens, datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels)), log(app.d(app.i_d).dens)','AlphaData',~isnan(app.d(app.i_d).dens'))
            plot(app.AxesDens,[datenum(app.start_date) datenum(app.end_date)],[app.d(app.i_d).height app.d(app.i_d).height],'k','linewidth',2); 
            % xlabel(app.AxesDens,'date'); ylabel(app.AxesDens,'[m]'); title(app.AxesDens,'Density [bird/m^3]'); colorbar(app.AxesDens);
            datetick(app.AxesDens,'x');  set(app.AxesDens, 'YDir', 'normal');
            set(app.AxesDens,'XTick',datenum(app.d(app.i_d).time(1):app.d(app.i_d).time(end)))
            set(app.AxesDens,'XTickLabel',datestr(app.d(app.i_d).time(1):app.d(app.i_d).time(end),'dd-mm'))
            axis(app.AxesDens,[datenum(app.start_date) datenum(app.end_date) 0 5000]); caxis(app.AxesDens,[-5 5])

            cla(app.AxesReflectivity); hold(app.AxesReflectivity,'on')
            imagesc(app.AxesReflectivity,datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels)), app.d(app.i_d).DBZH','AlphaData',~isnan(app.d(app.i_d).DBZH'))
            plot(app.AxesReflectivity,datenum(app.d(app.i_d).time), app.d(app.i_d).tcrw*5000/max( app.d(app.i_d).tcrw),'r-')
            % plot(app.AxesReflectivity,datenum(app.d(app.i_d).time([1 end])),[1 1].*0.005*5000/max( app.d(app.i_d).tcrw),'--r')
            % imagesc(app.AxesReflectivity,datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:app.d(app.i_d).maxrange), -50*ones(size(app.d(app.i_d).DBZH')),'AlphaData', 0.6*repmat(app.d(app.i_d).tcrw'>0.005,size(app.d(app.i_d).DBZH,2),1))
            % xlabel(app.AxesReflectivity,'date'); ylabel(app.AxesReflectivity,'[m]'); title(app.AxesReflectivity,'Reflectivity []'); colorbar(app.AxesReflectivity);
            datetick(app.AxesReflectivity,'x');  set(app.AxesReflectivity, 'YDir', 'normal')
            set(app.AxesReflectivity,'XTick',datenum(app.d(app.i_d).time(1):app.d(app.i_d).time(end)))
            set(app.AxesReflectivity,'XTickLabel',datestr(app.d(app.i_d).time(1):app.d(app.i_d).time(end),'dd-mm'))
            axis(app.AxesReflectivity,[datenum(app.start_date) datenum(app.end_date) 0 5000]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dc)
            app.d = dc;
            app.RadarSelectionDropDown.Items={app.d.name};
            refreshAxes(app);
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            app.dt=app.d(app.i_d).dens;
            refreshFig(app) 
        end

        % Value changed function: RadarSelectionDropDown
        function RadarSelectionDropDownValueChanged(app, event)
            app.i_d=find(strcmp(app.RadarSelectionDropDown.Value,{app.d.name}));
            refreshAxes(app)
        end

        % Button pushed function: previous
        function previousButtonPushed(app, event)
            app.i_d=app.i_d-1;
            refreshAxes(app)
        end

        % Button pushed function: next
        function nextButtonPushed(app, event)
            app.i_d=app.i_d+1;
            refreshAxes(app)
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
            [file,path] = uiputfile(['data/dc_' datestr(now,'YYYYmmdd_HHMM') '.mat'],'Save file name');
            dc=app.d;
            save([path file],'dc');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EditButton = uibutton(app.UIFigure, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.RadarSelectionDropDownLabel = uilabel(app.UIFigure);
            app.RadarSelectionDropDownLabel.Text = 'Radar Selection:';
            app.RadarSelectionDropDown = uidropdown(app.UIFigure);
            app.RadarSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @RadarSelectionDropDownValueChanged, true);
            app.AxesDens = uiaxes(app.UIFigure);
            title(app.AxesDens, 'Density [birds/m^3]')
            xlabel(app.AxesDens, 'Date')
            ylabel(app.AxesDens, 'Altitude [m]')
            app.AxesReflectivity = uiaxes(app.UIFigure);
            title(app.AxesReflectivity, 'Reflectivity')
            xlabel(app.AxesReflectivity, 'Date')
            ylabel(app.AxesReflectivity, 'Altitude [m]')
            app.previous = uibutton(app.UIFigure, 'push');
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.previous.Text = '<';
            app.next = uibutton(app.UIFigure, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.next.Text = '>';
            app.SavetofileButton = uibutton(app.UIFigure, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Cleaning(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end