classdef CleaningV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EditButton                   matlab.ui.control.Button
        RadarSelectionDropDownLabel  matlab.ui.control.Label
        RadarSelectionDropDown       matlab.ui.control.DropDown
        previous                     matlab.ui.control.Button
        next                         matlab.ui.control.Button
        SavetofileButton             matlab.ui.control.Button
        Figure2DropDownLabel         matlab.ui.control.Label
        DropDownFig2                 matlab.ui.control.DropDown
        Figure1editDropDownLabel     matlab.ui.control.Label
        DropDownFig1                 matlab.ui.control.DropDown
        LogScaleCheckBoxFig1         matlab.ui.control.CheckBox
        LogScaleCheckBoxFig2         matlab.ui.control.CheckBox
        AxesFig1                     matlab.ui.control.UIAxes
        AxesFig2                     matlab.ui.control.UIAxes
        ShowrainCheckBox             matlab.ui.control.CheckBox
        HidedayCheckBox              matlab.ui.control.CheckBox
        ShowinterpCheckBox           matlab.ui.control.CheckBox
    end


    properties (Access = private)
        d;
        dt;
        raint;
        i_d=1;
        fig=99;
        button;
        fig1='dens';
        fig2='DBZH';
        logScaleFig1=false;
        logScaleFig2=false;
        hideDay=false;
        showRain=false;
        showInterp=false;
    end

    methods (Access = private)
        
        function rect(app,val)
            set(app.button.nan,'Enable','off'); set(app.button.zero,'Enable','off'); set(app.button.fill,'Enable','off'); 
            set(app.button.fill0,'Enable','off'); set(app.button.fillnan,'Enable','off'); set(app.button.copyline,'Enable','off');set(app.button.refresh,'Enable','off');
            figure(app.fig); subplot(2,1,1);
            rect = getrect(gca);
            x = datenum(app.d(app.i_d).time);
            y = app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels));
            idx = x>=rect(1) & x<=rect(1)+rect(3);
            idy = y>=rect(2) & y<=rect(2)+rect(4);
            [X,Y]=meshgrid(x(idx),y(idy));
            figure(app.fig); hold on; scatter(X(:),Y(:),'r.')
            switch val
                case 'nan'
                    app.dt(idx,idy)=NaN;
                case'zero'
                    app.dt(idx,idy)=0;
                case 'fill'
                    V = app.dt;
                    id_nan =isnan(V);
                    V(idx,idy)=nan;
                    V=fillmissing(V,'pchip');
                    V(V<0)=0;
                    V(id_nan)=NaN;
                    app.dt=V;
                case 'fill0'
                    V = app.dt;
                    id_nan =isnan(V);
                    a=V(idx,idy);
                    a(a==0)=nan; 
                    V(idx,idy)=a;
                    V=fillmissing(V,'pchip');
                    V(V<0)=0;
                    V(id_nan)=NaN;
                    app.dt=V;
                case 'fillnan'
                    V = app.dt;
                    V(isnan(V)) = -1000;
                    a=V(idx,idy);
                    a(a<-999)=nan; 
                    V(idx,idy)=a;
                    V=fillmissing(V,'pchip');
                    V(V<-999)=NaN;
                    V(V<0)=0;
                    app.dt=V;
                case 'copyline'
                    id_copy = find(idy, 1 );
                    for id_y=1:id_copy-1
                        app.dt(idx,id_y) = app.dt(idx,id_copy);
                    end
                case 'multiply'
                    answer = str2double(inputdlg('Value to multiply by'));
                    app.dt(idx,idy) = answer.*app.dt(idx,idy);
                case 'markrain'
                    app.raint(idx)=true;
                case 'marknorain'
                    app.raint(idx)=false;
            end
            refreshFig(app)
        end
    
        function Viewset(app,val)
            a_x=axis;
            switch val
                case 'Viewall'
                    axis([datenum([app.d(1).time(1) app.d(1).time(end)]) a_x(3) a_x(4)]);
                case 'Viewmonth'
                    axis([mean(a_x(1:2))-15 mean(a_x(1:2))+15 a_x(3) a_x(4)]);
                case 'Viewprevmonth'
                    axis([a_x(1)-30 a_x(2)-30 a_x(3) a_x(4)]);
                case 'Viewnextmonth'
                    axis([a_x(1)+30 a_x(2)+30 a_x(3) a_x(4)]);
                 case 'Viewweek'
                    axis([mean(a_x(1:2))-3.5 mean(a_x(1:2))+3.5 a_x(3) a_x(4)]);
                case 'Viewprevweek'
                    axis([a_x(1)-7 a_x(2)-7 a_x(3) a_x(4)]);
                case 'Viewnextweek'
                    axis([a_x(1)+7 a_x(2)+7 a_x(3) a_x(4)]);

                case 'View3day'
                    axis([mean(a_x(1:2))-1.5 mean(a_x(1:2))+1.5 a_x(3) a_x(4)]);
                case 'Viewprev3day'
                    axis([a_x(1)-3 a_x(2)-3 a_x(3) a_x(4)]);
                case 'Viewnext3day'
                    axis([a_x(1)+3 a_x(2)+3 a_x(3) a_x(4)]);

            end
        end
        
        function refreshFig(app)

            figure(app.fig); a_x = axis; clf;
            ax1=subplot(2,1,1); hold on;
            if app.logScaleFig1
                var = log10(app.dt'+1);
            else
                var = app.dt';
            end
            
            if isfield(app.d,'day') && app.hideDay
                var(:,app.d(app.i_d).day)=NaN;
            end
%             if isfield(app.d,'rain') && app.showRain
%                 var(:,app.raint)=NaN;
%             end
            
            if ~(size(var,1)==app.d(app.i_d).levels)
                app.d(app.i_d).levels=size(var,1);
            end
            
            h=surf(datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels)), var,'AlphaData',~isnan(var),'EdgeColor','none');
            set(h,'ZData',get(h,'ZData')-10)
            plot([datenum(min(app.d(app.i_d).time)) datenum(max(app.d(app.i_d).time))],[app.d(app.i_d).height app.d(app.i_d).height],'k','linewidth',2); view(2)
            plot([datenum(min(app.d(app.i_d).time)) datenum(max(app.d(app.i_d).time))],[app.d(app.i_d).scatter_lim app.d(app.i_d).scatter_lim],'--k');
%             if isfield(app.d,'sunrise')
%                 plot(repmat(datenum(unique(app.d(app.i_d).sunrise))',2,1),[0 5000],'-k'); 
%                 plot(repmat(datenum(unique(app.d(app.i_d).sunset))',2,1),[0 5000],'-k');
%             end
            if isfield(app.d,'rain') && app.showRain
                tmp = double(app.raint); tmp(tmp==0)=nan;
                plot(datenum(app.d(app.i_d).time), 2500*tmp, '.r' ); 
            end
            if isfield(app.d,'interp') && app.showInterp
                [X,Y] = meshgrid(datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels)));
                scatter(X(app.d(app.i_d).interp'), Y(app.d(app.i_d).interp'), 'rx' ); 
            end
            datetick('x');set(gca,'XTick',round(datenum(app.d(app.i_d).time(1):app.d(app.i_d).time(end))))
            set(gca,'XTickLabel',datestr(round(datenum(app.d(app.i_d).time(1):app.d(app.i_d).time(end))),'dd-mm'))
            
            ax2=subplot(2,1,2); hold on;
            if app.logScaleFig2
                var = log10(app.d(app.i_d).(app.fig2)'+1);
            else
                var = app.d(app.i_d).(app.fig2)';
            end
            
            if ~(size(var,1)==app.d(app.i_d).levels)
                app.d(app.i_d).levels=size(var,1);
            end
                        
            h=surf(datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels)), var,'AlphaData',~isnan(var),'EdgeColor','none');
            set(h,'ZData',get(h,'ZData')-100);
            if isfield(app.d,'tcrw')
                plot(datenum(app.d(app.i_d).time), app.d(app.i_d).tcrw*5000/max( app.d(app.i_d).tcrw),'r-','linewidth',2); 
%                 plot(datenum([app.d(app.i_d).time(1) app.d(app.i_d).time(end)]),[0 0]*5000/max( app.d(app.i_d).tcrw),'--r');
%                 plot(datenum([app.d(app.i_d).time(1) app.d(app.i_d).time(end)]),[-10 -10]*5000/max( app.d(app.i_d).tcrw),'--r');
%                 plot(datenum([app.d(app.i_d).time(1) app.d(app.i_d).time(end)]),[10 10]*5000/max( app.d(app.i_d).tcrw),'--r');
                text(datenum(app.d(app.i_d).time(1)),0.0029*5000/max( app.d(app.i_d).tcrw),'threshold at 0.0029')
            end
            view(2)
            datetick('x');set(gca,'XTick',round(datenum(app.d(app.i_d).time(1):app.d(app.i_d).time(end))))
            set(ax2,'XTickLabel',datestr(round(datenum(app.d(app.i_d).time(1):app.d(app.i_d).time(end))),'dd-mm'))
            
            linkaxes([ax1,ax2],'xy')
            if ~all(a_x==[0 1 0 1]); axis(ax2,a_x); else, axis tight; end
                        
            app.button.nan=uicontrol('Parent',app.fig,'Style','pushbutton','String','Fill with NaN','Units','normalized','Position',[0.01 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'nan'));
            app.button.zero=uicontrol('Parent',app.fig,'Style','pushbutton','String','Fill with 0','Units','normalized','Position',[0.08 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'zero'));
            app.button.fill=uicontrol('Parent',app.fig,'Style','pushbutton','String','Interpolation','Units','normalized','Position',[0.15 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'fill'));
            app.button.fill0=uicontrol('Parent',app.fig,'Style','pushbutton','String','Interpolation only 0','Units','normalized','Position',[0.22 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'fill0'));
            app.button.fillnan=uicontrol('Parent',app.fig,'Style','pushbutton','String','Interpolation only NaN','Units','normalized','Position',[0.29 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'fillnan'));
            app.button.copyline=uicontrol('Parent',app.fig,'Style','pushbutton','String','Copy line below','Units','normalized','Position',[0.36 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'copyline'));
            app.button.multiply=uicontrol('Parent',app.fig,'Style','pushbutton','String','Multiply value','Units','normalized','Position',[0.43 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'multiply'));
            
            app.button.markrain=uicontrol('Parent',app.fig,'Style','pushbutton','String','Mark as rain','Units','normalized','Position',[0.60 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'markrain'));
            app.button.marknorain=uicontrol('Parent',app.fig,'Style','pushbutton','String','Mark as non-rain','Units','normalized','Position',[0.67 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) rect(app,'marknorain'));
            
            app.button.refresh=uicontrol('Parent',app.fig,'Style','pushbutton','String','Save changes','Units','normalized','Position',[0.92 0.95 0.07 0.04],'Visible','on','Callback', @(source,event) saveFig(app));
        
            app.button.prev=uicontrol('Parent',app.fig,'Style','pushbutton','String','All','Units','normalized','Position',[0.01 0.5 0.07 0.04],'Visible','on','Callback', @(source,event) Viewset(app,'Viewall'));
            app.button.prev=uicontrol('Parent',app.fig,'Style','pushbutton','String','<','Units','normalized','Position',[0.12 0.5 0.03 0.04],'Visible','on','Callback', @(source,event) Viewset(app,'Viewprevmonth'));
            app.button.prev=uicontrol('Parent',app.fig,'Style','pushbutton','String','Month','Units','normalized','Position',[0.15 0.5 0.07 0.04],'Visible','on','Callback', @(source,event) Viewset(app,'Viewmonth'));
            app.button.prev=uicontrol('Parent',app.fig,'Style','pushbutton','String','>','Units','normalized','Position',[0.22 0.5 0.03 0.04],'Visible','on','Callback', @(source,event) Viewset(app,'Viewnextmonth'));
            app.button.prev=uicontrol('Parent',app.fig,'Style','pushbutton','String','<','Units','normalized','Position',[0.33 0.5 0.03 0.04],'Visible','on','Callback', @(source,event) Viewset(app,'Viewprevweek'));
            app.button.prev=uicontrol('Parent',app.fig,'Style','pushbutton','String','Week','Units','normalized','Position',[0.36 0.5 0.07 0.04],'Visible','on','Callback', @(source,event) Viewset(app,'Viewweek'));
            app.button.prev=uicontrol('Parent',app.fig,'Style','pushbutton','String','>','Units','normalized','Position',[0.43 0.5 0.03 0.04],'Visible','on','Callback', @(source,event) Viewset(app,'Viewnextweek'));
            app.button.prev=uicontrol('Parent',app.fig,'Style','pushbutton','String','<','Units','normalized','Position',[0.54 0.5 0.03 0.04],'Visible','on','Callback', @(source,event) Viewset(app,'Viewprev3day'));
            app.button.prev=uicontrol('Parent',app.fig,'Style','pushbutton','String','3 days','Units','normalized','Position',[0.57 0.5 0.07 0.04],'Visible','on','Callback', @(source,event) Viewset(app,'View3day'));
            app.button.prev=uicontrol('Parent',app.fig,'Style','pushbutton','String','>','Units','normalized','Position',[0.64 0.5 0.03 0.04],'Visible','on','Callback', @(source,event) Viewset(app,'Viewnext3day'));
            
        end
        
        function saveFig(app)
            app.d(app.i_d).(app.fig1)=app.dt;
            if isfield(app.d,'rain')
                app.d(app.i_d).rain=app.raint;
            end
            refreshAxes(app)
        end
        
        function refreshAxes(app)
            app.RadarSelectionDropDown.Value = app.d(app.i_d).name;
            
            cla(app.AxesFig1); hold(app.AxesFig1,'on')
            if app.logScaleFig1
                var = log10(app.d(app.i_d).(app.fig1)');
            else
                var = app.d(app.i_d).(app.fig1)';
            end
            
            if isfield(app.d,'rain') && app.showRain
                if any(size(app.d(app.i_d).rain)==1)
                    var(:,app.d(app.i_d).rain)=NaN;
                else
                    var(app.d(app.i_d).rain')=NaN;
                end
            end
            if isfield(app.d,'day') && app.hideDay
                var(:,app.d(app.i_d).day)=NaN;
            end
            if isfield(app.d,'rem') && app.showInterp
                var(:,app.d(app.i_d).rem)=NaN;
            end
            
            imagesc(app.AxesFig1, datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels)), var, 'AlphaData',~isnan(var))
            plot(app.AxesFig1,[datenum(min(app.d(app.i_d).time)) datenum(max(app.d(app.i_d).time))],[app.d(app.i_d).height app.d(app.i_d).height],'k','linewidth',2); 
            xlabel(app.AxesFig1,'date'); ylabel(app.AxesFig1,'[m]'); colorbar(app.AxesFig1);
            datetick(app.AxesFig1,'x');  set(app.AxesFig1, 'YDir', 'normal');
            set(app.AxesFig1,'XTick',datenum(app.d(app.i_d).time(1):10:app.d(app.i_d).time(end)))
            set(app.AxesFig1,'XTickLabel',datestr(app.d(app.i_d).time(1):10:app.d(app.i_d).time(end),'dd-mm'))
            axis(app.AxesFig1,[datenum(min(app.d(app.i_d).time)) datenum(max(app.d(app.i_d).time)) 0 5000]); % caxis(app.AxesFig1,[-5 5])
            
            if isfield(app.d,'scatter_lim')
                plot(app.AxesFig1,[datenum(min(app.d(app.i_d).time)) datenum(max(app.d(app.i_d).time))],[app.d(app.i_d).scatter_lim*200-200 app.d(app.i_d).scatter_lim*200-200],'r','linewidth',2); view(2)
            end

            cla(app.AxesFig2); hold(app.AxesFig2,'on')
            if app.logScaleFig2
                var = log10(app.d(app.i_d).(app.fig2)'+1);
            else
                var = app.d(app.i_d).(app.fig2)';
            end
            imagesc(app.AxesFig2,datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:double(app.d(app.i_d).levels)),var,'AlphaData',~isnan(var))
            if isfield(app.d,'tcrw')
                plot(app.AxesFig2,datenum(app.d(app.i_d).time), app.d(app.i_d).tcrw*5000/max( app.d(app.i_d).tcrw),'r-')
                plot(app.AxesFig2,datenum([app.d(app.i_d).time(1) app.d(app.i_d).time(end)]),[0.0029 0.0029]*5000/max( app.d(app.i_d).tcrw),'--r');
                text(app.AxesFig2,datenum(app.d(app.i_d).time(1)),0.0029*5000/max( app.d(app.i_d).tcrw),'threshold at 0.0029')
            end
            
            % plot(app.AxesFig2,datenum(app.d(app.i_d).time([1 end])),[1 1].*0.005*5000/max( app.d(app.i_d).tcrw),'--r')
            % imagesc(app.AxesFig2,datenum(app.d(app.i_d).time), app.d(app.i_d).interval*(1/2:app.d(app.i_d).maxrange), -50*ones(size(app.d(app.i_d).DBZH')),'AlphaData', 0.6*repmat(app.d(app.i_d).tcrw'>0.005,size(app.d(app.i_d).DBZH,2),1))
            % xlabel(app.AxesFig2,'date'); ylabel(app.AxesFig2,'[m]'); title(app.AxesFig2,'Reflectivity []'); colorbar(app.AxesFig2);
            datetick(app.AxesFig2,'x');  set(app.AxesFig2, 'YDir', 'normal')
            set(app.AxesFig2,'XTick',datenum(app.d(app.i_d).time(1):10:app.d(app.i_d).time(end)))
            set(app.AxesFig2,'XTickLabel',datestr(app.d(app.i_d).time(1):10:app.d(app.i_d).time(end),'dd-mm'))
            axis(app.AxesFig2,[datenum(min(app.d(app.i_d).time)) datenum(max(app.d(app.i_d).time)) 0 5000]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dc)
            app.d = dc;
            app.RadarSelectionDropDown.Items={app.d.name};
            fn=fieldnames(app.d);
            for i=numel(fn):-1:1
                if ~all( size(app.d(1).(fn{i})) > 4 )
                    fn(i)=[];
                end
            end
            app.DropDownFig2.Items=fn;
            app.DropDownFig1.Items=fn;
            refreshAxes(app);
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            app.dt=app.d(app.i_d).(app.fig1);
            if isfield(app.d,'rain')
                app.raint=app.d(app.i_d).rain;
            end
            refreshFig(app) 
        end

        % Value changed function: RadarSelectionDropDown
        function RadarSelectionDropDownValueChanged(app, event)
            app.i_d=find(strcmp(app.RadarSelectionDropDown.Value,{app.d.name}));
            refreshAxes(app)
        end

        % Button pushed function: previous
        function previousButtonPushed(app, event)
            app.i_d=max(app.i_d-1,1);
            refreshAxes(app)
        end

        % Button pushed function: next
        function nextButtonPushed(app, event)
            app.i_d=min(app.i_d+1,numel(app.d));
            refreshAxes(app)
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
            [file,path] = uiputfile(['data/dc_' datestr(now,'YYYYmmdd_HHMM') '.mat'],'Save file name');
            dc=app.d;
            save([path file],'dc','-v7.3');
        end

        % Value changed function: DropDownFig2
        function DropDownFig2ValueChanged(app, event)
            app.fig2 = app.DropDownFig2.Value;
            refreshAxes(app)
        end

        % Value changed function: DropDownFig1
        function DropDownFig1ValueChanged(app, event)
            app.fig1 = app.DropDownFig1.Value;
            refreshAxes(app)
        end

        % Value changed function: LogScaleCheckBoxFig1
        function LogScaleCheckBoxFig1ValueChanged(app, event)
            app.logScaleFig1 = app.LogScaleCheckBoxFig1.Value;
            refreshAxes(app)
        end

        % Value changed function: LogScaleCheckBoxFig2
        function LogScaleCheckBoxFig2ValueChanged(app, event)
            app.logScaleFig2 = app.LogScaleCheckBoxFig2.Value;
            refreshAxes(app)
        end

        % Value changed function: HidedayCheckBox
        function HidedayCheckBoxValueChanged(app, event)
            app.hideDay = app.HidedayCheckBox.Value;
            refreshAxes(app)
        end

        % Value changed function: ShowrainCheckBox
        function ShowrainCheckBoxValueChanged(app, event)
            app.showRain = app.ShowrainCheckBox.Value;
            refreshAxes(app)
        end

        % Value changed function: ShowinterpCheckBox
        function ShowinterpCheckBoxValueChanged(app, event)
            app.showInterp = app.ShowinterpCheckBox.Value;
            refreshAxes(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EditButton = uibutton(app.UIFigure, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.RadarSelectionDropDownLabel = uilabel(app.UIFigure);
            app.RadarSelectionDropDownLabel.Text = 'Radar Selection:';
            app.RadarSelectionDropDown = uidropdown(app.UIFigure);
            app.RadarSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @RadarSelectionDropDownValueChanged, true);
            app.previous = uibutton(app.UIFigure, 'push');
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.previous.Text = '<';
            app.next = uibutton(app.UIFigure, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.next.Text = '>';
            app.SavetofileButton = uibutton(app.UIFigure, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file';
            app.Figure2DropDownLabel = uilabel(app.UIFigure);
            app.Figure2DropDownLabel.Text = 'Figure 2';
            app.DropDownFig2 = uidropdown(app.UIFigure);
            app.DropDownFig2.Items = {'DBZH', 'dens', 'ff', 'dd', 'eta', 'sd_vvp'};
            app.DropDownFig2.ValueChangedFcn = createCallbackFcn(app, @DropDownFig2ValueChanged, true);
            app.DropDownFig2.Value = 'DBZH';
            app.Figure1editDropDownLabel = uilabel(app.UIFigure);
            app.Figure1editDropDownLabel.Text = 'Figure 1 (edit)';
            app.DropDownFig1 = uidropdown(app.UIFigure);
            app.DropDownFig1.Items = {'dens', 'DBZH', 'ff', 'dd', 'eta', 'sd_vvp'};
            app.DropDownFig1.ValueChangedFcn = createCallbackFcn(app, @DropDownFig1ValueChanged, true);
            app.DropDownFig1.Value = 'dens';
            app.LogScaleCheckBoxFig1 = uicheckbox(app.UIFigure);
            app.LogScaleCheckBoxFig1.ValueChangedFcn = createCallbackFcn(app, @LogScaleCheckBoxFig1ValueChanged, true);
            app.LogScaleCheckBoxFig1.Text = 'Log Scale Figure 1';
            app.LogScaleCheckBoxFig2 = uicheckbox(app.UIFigure);
            app.LogScaleCheckBoxFig2.ValueChangedFcn = createCallbackFcn(app, @LogScaleCheckBoxFig2ValueChanged, true);
            app.LogScaleCheckBoxFig2.Text = 'Log Scale Figure 2';
            app.AxesFig1 = uiaxes(app.UIFigure);
            ylabel(app.AxesFig1, 'Altitude [m]')
            app.AxesFig2 = uiaxes(app.UIFigure);
            xlabel(app.AxesFig2, 'Date')
            ylabel(app.AxesFig2, 'Altitude [m]')
            app.ShowrainCheckBox = uicheckbox(app.UIFigure);
            app.ShowrainCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowrainCheckBoxValueChanged, true);
            app.ShowrainCheckBox.Text = 'Show rain';
            app.HidedayCheckBox = uicheckbox(app.UIFigure);
            app.HidedayCheckBox.ValueChangedFcn = createCallbackFcn(app, @HidedayCheckBoxValueChanged, true);
            app.HidedayCheckBox.Text = 'Hide day';
            app.ShowinterpCheckBox = uicheckbox(app.UIFigure);
            app.ShowinterpCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowinterpCheckBoxValueChanged, true);
            app.ShowinterpCheckBox.Text = 'Show interp';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CleaningV(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end